classdef TennisProjectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        TennisTrackerTab            matlab.ui.container.Tab
        StartServeVolleyButton      matlab.ui.control.Button
        SelectDataFileButton        matlab.ui.control.Button
        SnickConnectionLED          matlab.ui.control.Lamp
        SnickConnectButton          matlab.ui.control.Button
        SnickDisconnetButton        matlab.ui.control.Button
        BlenderConnectionLabel_2    matlab.ui.control.Label
        BlendConnectionLED          matlab.ui.control.Lamp
        BlenderConnectionLabel      matlab.ui.control.Label
        BlendConnectButton          matlab.ui.control.Button
        BlendDisconnetButton        matlab.ui.control.Button
        BounceBoundsLamp            matlab.ui.control.Lamp
        BounceBoundsGuideText       matlab.ui.control.TextArea
        BounceBoundsLampLabel       matlab.ui.control.Label
        CalulatedCoordinateDataViewLabel  matlab.ui.control.Label
        DataTable                   matlab.ui.control.Table
        RightCameraView             matlab.ui.control.UIAxes
        LeftCameraView              matlab.ui.control.UIAxes
        StereoCalibratorTab         matlab.ui.container.Tab
        SendCalibration             matlab.ui.control.Button
        GuideTextArea               matlab.ui.control.TextArea
        GuideTextAreaLabel          matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        PropogationErrorLabel       matlab.ui.control.Label
        BoardPlacementVisualizationLabel  matlab.ui.control.Label
        RefreshButton               matlab.ui.control.Button
        AddImagesButton             matlab.ui.control.Button
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        CORTab                      matlab.ui.container.Tab
        SurfaceDropDown             matlab.ui.control.DropDown
        SurfaceDropDownLabel        matlab.ui.control.Label
        CORTable                    matlab.ui.control.Table
        MeasureCORButton            matlab.ui.control.Button
        BaselineCORButton           matlab.ui.control.Button
        CORAxes                     matlab.ui.control.UIAxes
        AccuracyPlotsTab            matlab.ui.container.Tab
        LoadMeasuredDataButton      matlab.ui.control.Button
        LoadGroundTruthButton       matlab.ui.control.Button
        OverallAccuracyLabel        matlab.ui.control.Label
        AccuracyLabel               matlab.ui.control.Label
        ErrorLabel                  matlab.ui.control.Label
        SystemAccuracyLabel         matlab.ui.control.Label
        BasedonCameraPositionLabel  matlab.ui.control.Label
        BasedonBallPositionLabel    matlab.ui.control.Label
        BallAccuracyZ               matlab.ui.control.UIAxes
        BallAccuracyY               matlab.ui.control.UIAxes
        BallAccuracyX               matlab.ui.control.UIAxes
        CamAccuracy                 matlab.ui.control.UIAxes
        PlaybackTab                 matlab.ui.container.Tab
        ZDisplay                    matlab.ui.control.NumericEditField
        ZLabel                      matlab.ui.control.Label
        ReplayButton                matlab.ui.control.Button
        YDisplay                    matlab.ui.control.NumericEditField
        XDisplay                    matlab.ui.control.NumericEditField
        YLabel                      matlab.ui.control.Label
        PlayBackMenuLabel           matlab.ui.control.Label
        XEditFieldLabel             matlab.ui.control.Label
        TrajectoryGraphLabel        matlab.ui.control.Label
        ReplayPOVLabel              matlab.ui.control.Label
        ReplayGraphR                matlab.ui.control.UIAxes
        TrajectoryGraph             matlab.ui.control.UIAxes
        ReplayGraphL                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Blender Properties
        BlenderFolderPath string % Path to Blender Class
        SnickFolderPath string   % Path to Blender Class
        DataFileFolder
        ActiveDataFilePath
        BlendClient 
        SnickClient

        % Stereo Calibrator Image Paths & Data
        LeftImageFolderPath
        RightImageFolderPath
        stereoParams
        baselinemm
        focallengthmm

        % COR Properties
        CORFolderPath string % Path to COR Scripts
        sampledServe         % data plots
        GroundTruthData      % Expected Values for accuracy plots
        MeasuredData         % Calculated Values for plots

        %Raw Data
        LastModifiedTime   % Last known modification timestamp of the .dat file
        FileMonitorTimer   % Timer object for auto-updating
        GlobalDataFilePath % Full path to your XYZ-Time .dat file
        currentLeftImage   % Intermediarry between blender and snick
        currentRightImage  % Intermediarry between blender and snick
        baselineLeft
        baselineRight
    end


    methods (Access = private)

%--------------------------------------------------------------------------
%----- TENNIS TRACKER FUNCTIONS -------------------------------------------
%--------------------------------------------------------------------------
        function updateBallStatusLamp(app)
            % Define court boundaries (meters)
            courtX_min = -5.5;
            courtX_max = 5.5;
            courtY_min = -11.88;
            courtY_max = 0;
            groundZThreshold = 0.2;  % touching ground if Z <= 20cm
        
            % Check if file exists
            if ~isfile(app.GlobalDataFilePath)
                app.BounceBoundsLamp.Color = [0.7 0.7 0.7]; % Gray if no data
                return;
            end
        
            % Read latest data
            rawData = readmatrix(app.GlobalDataFilePath);
        
            if isempty(rawData) || size(rawData,2) < 3
                app.BounceBoundsLamp.Color = [0.7 0.7 0.7]; % Gray if bad data
                return;
            end
        
            % Take the last row (most recent point)
            latestRow = rawData(end, :);
        
            x = latestRow(1);
            z = latestRow(2);
            y = latestRow(3);
        
            % Lamp logic
            if z <= groundZThreshold
                % Ball touching ground
                if (x >= courtX_min && x <= courtX_max) && (y >= courtY_min && y <= courtY_max)
                    % Inside court
                    app.BounceBoundsLamp.Color = 'green';
                else
                    % Touching ground but out of bounds
                    app.BounceBoundsLamp.Color = 'red';
                end
            else
                % In the air
                app.BounceBoundsLamp.Color = [0.7 0.7 0.7]; % Gray
            end
        end

%--------------------------------------------------------------------------
%----- STEREO CALIBRATION FUNCTIONS ----------------------------------------
%--------------------------------------------------------------------------
        function plotExtrinsics(app, stereoParams)
            tempFig = figure('Visible', 'off');
            showExtrinsics(stereoParams, 'CameraCentric');
            tempAx = gca;
            
            cla(app.UIAxes_2);
            copyobj(allchild(tempAx), app.UIAxes_2);
            app.UIAxes_2.XLim = tempAx.XLim;
            app.UIAxes_2.YLim = tempAx.YLim;
            app.UIAxes_2.ZLim = tempAx.ZLim;
            app.UIAxes_2.XColor = [1 1 1];
            app.UIAxes_2.YColor = [1 1 1];
            app.UIAxes_2.ZColor = [1 1 1];
            app.UIAxes_2.Box = 'on';
            close(tempFig);
        end
%--------------------------------------------------------------------------
        function plotReprojectionErrors(app, stereoParams)
            tempFig = figure('Visible', 'off');
            showReprojectionErrors(stereoParams);
            tempAx = gca;
    
            cla(app.UIAxes);
            copyobj(allchild(tempAx), app.UIAxes);
            app.UIAxes.XLim = tempAx.XLim;
            app.UIAxes.YLim = tempAx.YLim;
            app.UIAxes.XColor = [1 1 1];
            app.UIAxes.YColor = [1 1 1];
            app.UIAxes.Box = 'on';
            close(tempFig);
        end
%--------------------------------------------------------------------------
        function [stereoParams, I1, I2, estimationErrors] = calibrateStereoCameras(app)
            % List all .png images in each selected folder
            leftFiles = dir(fullfile(app.LeftImageFolderPath, '*.png'));
            rightFiles = dir(fullfile(app.RightImageFolderPath, '*.png'));
            
            % Sort by filename to maintain order
            leftFiles = sort({leftFiles.name});
            rightFiles = sort({rightFiles.name});
            
            % Check for matching file counts
            if numel(leftFiles) ~= numel(rightFiles)
                uialert(app.UIFigure, ...
                    'Left and Right image folders must have the same number of images.', ...
                    'Image Count Mismatch');
                error('Image count mismatch between left and right folders.');
            end
            
            % Construct full file paths
            imageFileNames1 = fullfile(app.LeftImageFolderPath, leftFiles);
            imageFileNames2 = fullfile(app.RightImageFolderPath, rightFiles);
    
    
            detector = vision.calibration.stereo.CheckerboardDetector();
             [imagePoints, ~] = detectPatternPoints(detector, imageFileNames1, imageFileNames2);
    
            squareSize = 900;  % millimeters
            worldPoints = generateWorldPoints(detector, 'SquareSize', squareSize);
    
             I1 = imread(imageFileNames1{1});
             I2 = imread(imageFileNames2{1});
            [mrows, ncols, ~] = size(I1);
    
            [stereoParams, ~, estimationErrors] = estimateCameraParameters( ...
                imagePoints, worldPoints, ...
                'EstimateSkew', false, ...
                'EstimateTangentialDistortion', false, ...
                'NumRadialDistortionCoefficients', 2, ...
                'WorldUnits', 'millimeters', ...
                'ImageSize', [mrows, ncols]);
        end
%--------------------------------------------------------------------------
        function updateCameraTable(app, stereoParams)
            % Camera positions in world coordinates
            center1 = [0, 0, 0];
            R2 = stereoParams.RotationOfCamera2;
            T2 = stereoParams.TranslationOfCamera2';
            center2 = -R2' * T2;
            
            f1 = mean(stereoParams.CameraParameters1.FocalLength);
            f2 = mean(stereoParams.CameraParameters2.FocalLength);
            
            app.UITable.Data = {
                'Left',  center1(1), center1(2), center1(3), f1;
                'Right', center2(1), center2(2), center2(3), f2;
            };

            app.baselinemm = center2(1);
            app.focallengthmm = f2;
        end
%--------------------------------------------------------------------------
%----- SYSTEM ACCURACY FUNCTIONS ------------------------------------------
%--------------------------------------------------------------------------
        function displayAccuracy(app) 
            TruthData = app.GroundTruthData;
            Measured = app.MeasuredData;
            
            if size(TruthData,1) ~= size(Measured,1)
                uialert(app.UIFigure, ...
                    'Datasets must have the same number of points!', ...
                    'Size Error');
                return;
            end
    
            X_gt = TruthData(:,1);
            Y_gt = TruthData(:,2);
            Z_gt = TruthData(:,3);
    
            X_measured = Measured(:,1);
            Y_measured = Measured(:,2);
            Z_measured = Measured(:,3);
            
            % Plot for X Axis
            plot(app.BallAccuracyX, X_gt, X_measured, 'DisplayName', 'Measured');
            hold(app.BallAccuracyX, 'on');
            plot(app.BallAccuracyX, X_gt, X_gt, 'DisplayName', 'Ideal');
            hold(app.BallAccuracyX, 'off');
            grid(app.BallAccuracyX, 'on');
            xlabel(app.BallAccuracyX, 'Actual X');
            ylabel(app.BallAccuracyX, 'Measured X');
            title(app.BallAccuracyX, 'Horizontal (X-axis)');
            legend(app.BallAccuracyX, 'Location', 'best');
    
            % Plot for Y Axis
            plot(app.BallAccuracyY, Y_gt, Y_measured, 'DisplayName', 'Measured');
            hold(app.BallAccuracyY, 'on');
            plot(app.BallAccuracyY, Y_gt, Y_gt, 'DisplayName', 'Ideal');
            hold(app.BallAccuracyY, 'off');
            grid(app.BallAccuracyY, 'on');
            xlabel(app.BallAccuracyY, 'Actual Y');
            ylabel(app.BallAccuracyY, 'Measured Y');
            title(app.BallAccuracyY, 'Vertical (Y-axis)');
            legend(app.BallAccuracyY, 'Location', 'best');
    
            % Plot for Z Axis
            plot(app.BallAccuracyZ, Z_gt, Z_measured, 'DisplayName', 'Measured');
            hold(app.BallAccuracyZ, 'on');
            plot(app.BallAccuracyZ, Z_gt, Z_gt, 'DisplayName', 'Ideal');
            hold(app.BallAccuracyZ, 'off');
            grid(app.BallAccuracyZ, 'on');
            xlabel(app.BallAccuracyZ, 'Actual Z');
            ylabel(app.BallAccuracyZ, 'Measured Z');
            title(app.BallAccuracyZ, 'Depth (Z-axis)');
            legend(app.BallAccuracyZ, 'Location', 'best');
    
            abs_error_X = abs(X_measured - X_gt);
            abs_error_Y = abs(Y_measured - Y_gt);
            abs_error_Z = abs(Z_measured - Z_gt);
    
            %max_error_X = max(abs_error_X);
            %max_error_Y = max(abs_error_Y);
            %max_error_Z = max(abs_error_Z);
    
            accuracy_X = (1 - mean(abs_error_X) / mean(abs(X_gt))) * 100;
            accuracy_Y = (1 - mean(abs_error_Y) / mean(abs(Y_gt))) * 100;
            accuracy_Z = (1 - mean(abs_error_Z) / mean(abs(Z_gt))) * 100;
            % mean value as overall accuracy
            overall_accuracy = mean([accuracy_X, accuracy_Y, accuracy_Z]);
    
    
            % === Display on GUI ===
            app.OverallAccuracyLabel.Text = sprintf('System Accuracy: %.2f%%', overall_accuracy);
    
        end
%--------------------------------------------------------------------------
       function loadGlobalXYZData(app)
            if ~isfile(app.GlobalDataFilePath)
                return; % File missing, skip
            end
        
            rawData = readmatrix(app.GlobalDataFilePath);
        
            if size(rawData,2) ~= 4
                % Handle unexpected format gracefully
                return;
            end
        
            % Rearrange from X Z Y Time → X Y Z Time
            rearrangedData = rawData(:, [1, 3, 2, 4]);
        
            app.DataTable.Data = num2cell(rearrangedData);
        end
%--------------------------------------------------------------------------
        function checkFileUpdate(app)
            if ~isfile(app.GlobalDataFilePath)
                return;
            end
            fileInfo = dir(app.GlobalDataFilePath);
            currentModifiedTime = fileInfo.datenum;
        
            if currentModifiedTime > app.LastModifiedTime
                app.LastModifiedTime = currentModifiedTime;
                app.loadGlobalXYZData(); % <-- Update table
                app.updateBallStatusLamp(); % Update Lamp
            end
        end

%--------------------------------------------------------------------------
%----- SNICKERDOODLE FUNCTIONS --------------------------------------------
%--------------------------------------------------------------------------

function snickConnection(app)
    % check to make sure that the snick connection isn't already present
    if isprop(app, 'snickClient') && ~isempty(app.SnickClient) && isvalid(app.SnickClient)
        app.SnickConnectionLED.Color = 'green';
        uialert(app.UIFigure,...
            '[MATLAB Client] Connection already open!', ...
            'Blender Connection Error');
        return;
    end

    try
        client = snickerdoodleClient.openConnection();
    catch
        uialert(app.UIFigure, ...
            '[MATLAB Client] Failed to connect to snickerdoodle', ...
            'Snickerdoodle Connection Error');
        client = [];
    end

    % Store and confirm connection
    if ~isempty(client) && isvalid(client)
        app.SnickClient = client;
        app.SnickConnectionLED.Color = 'green';
    else
        uialert(app.UIFigure, ...
            '[MATLAB Client] Connection failed or is invalid', ...
            'Snickerdoodle Connection Error');
    end
end

% Button pushed function: BlendDisconnetButton
function snickDisconnect(app)
    % Check if client exists and is valid
    if isprop(app, 'SnickClient') && ~isempty(app.SnickClient) && isvalid(app.SnickClient)
        try
            snickerdoodleClient.closeConnection(app.SnickClient);
        catch
            uialert(app.UIFigure, ...
                '[MATLAB Client] Failed to disconnect from Snickerdoodle', ...
                'Blender Disconnection Error');
            return;
        end
    end

    % Clear the client reference and update UI
    app.SnickClient = [];
    app.SnickConnectionLED.Color = 'red';
end

function updateSnickerdoodleParam(app);
    % Magic number lol (its the pixelsize based off the sensor)
    pixelsizemm = 0.006;
    % Check if client exists and is valid
    if isprop(app, 'SnickClient') && ~isempty(app.SnickClient) && isvalid(app.SnickClient)
        try
            snickerdoodleClient.calibrationRequest( ...
                app.SnickClient, ...
                app.baselinemm, ...
                app.focallengthmm, ...
                pixelsizemm);
        catch
            uialert(app.UIFigure, ...
                '[MATLAB Client] Failed to calibrate Snickerdoodle: no connection', ...
                'Blender Disconnection Error');
            return;
        end
    end
end


%--------------------------------------------------------------------------
%----- BLENDER FUNCTIONS --------------------------------------------
%--------------------------------------------------------------------------
        function blenderConnection(app)
            % Check if client already exists and is connected
            if isprop(app, 'BlendClient') && ~isempty(app.BlendClient) && isvalid(app.BlendClient)
                app.BlendConnectionLED.Color = 'green';
                uialert(app.UIFigure, ...
                    '[MATLAB Client] Connection already open!', ...
                    'Blender Connection Error');
                return;
            end
            
            try
                client = blenderClient.openConnection();
            catch
                uialert(app.UIFigure, ...
                    '[MATLAB Client] Failed to connect to blender', ...
                    'Blender Connection Error');
                client = [];
            end
        
            % Store and confirm connection
            if ~isempty(client) && isvalid(client)
                app.BlendClient = client;
                app.BlendConnectionLED.Color = 'green';
                app.SelectDataFileButton.Enable = 'on';
            else
                uialert(app.UIFigure, ...
                    '[MATLAB Client] Connection failed or is invalid', ...
                    'Blender Connection Error');
                app.SelectDataFileButton.Enable = 'off';
            end
        end
        
        % Button pushed function: BlendDisconnetButton
        function blenderDisconnect(app)
            % Check if client exists and is valid
            if isprop(app, 'BlendClient') && ~isempty(app.BlendClient) && isvalid(app.BlendClient)
                try
                    blenderClient.closeConnection(app.BlendClient);
                catch
                    uialert(app.UIFigure, ...
                        '[MATLAB Client] Failed to disconnect from Blender', ...
                        'Blender Disconnection Error');
                    return;
                end
            end
        
            % Clear the client reference and update UI
            app.BlendClient = [];
            app.BlendConnectionLED.Color = 'red';
            app.SelectDataFileButton.Enable = 'off';
        end

        function readDatFileForBlender(app)
            % Prompt user to select a file, only looking for
            % files with the .dat type
            [fileName, filePath] = uigetfile('*.dat', ...
                'Select Server or Volley Data...', ...
                app.DataFileFolder);

            % Check if the user canceled the dialog
            if isequal(fileName, 0)
                disp('[INFO] File selection canceled.');
                app.ActiveDataFilePath = [];
                app.StartServeVolleyButton.Enable = 'off';
                return;
            end

            % Build the full file path & read
            fullFile = fullfile(filePath, fileName);
            try
                selectedFile = readtable(fullFile);
                disp(['[INFO] Loaded ', ...
                    num2str(height(selectedFile)), ...
                    ' entries from: ', ...
                    fileName]);
            catch ME
                disp('[ERROR] Failed to read file:');
                disp(ME.message);
                uialert(app.UIFigure, ...
                    'Could not load .dat file. Check formatting.', ...
                    'File Error');
                app.StartServeVolleyButton.Enable = 'off';
            end

            % Check that there are exactly 3 columns
            if width(selectedFile) ~= 3 || any(any(ismissing(selectedFile)))
                uialert(app.UIFigure, ...
                    'File does not contain 3 full columns of data!', ...
                    'File Error');
                app.ActiveDataFilePath = [];
                app.StartServeVolleyButton.Enable = 'off';
                return
            end

            % Set global variable to hold dat file contents
            app.ActiveDataFilePath = readtable(fullFile);
            app.StartServeVolleyButton.Enable = 'on';
            
        end

        function blenderObjectXform(app)
           % Convert to numeric matrix or else we get error
           % "All input arguments must be tables or cell arrays"
           % I fucking hate matlab...
           xyz = table2array(app.ActiveDataFilePath);
           for i = 1:10:size(xyz, 1)
               x = xyz(i, 1);
               z = xyz(i, 2);
               y = xyz(i, 3);
               blenderClient.transformObject( ...
                   app.BlendClient, ...
                   blenderClient.TennisBall, ...
                   x, y, z, ...
                   0.0, 0.0, 0.0)

               width = 752;
               height = 480;

               % TODO: add exit for this operation. Stopping the blender
               % server is pretty tramatic for the program lol
               [app.currentLeftImage, app.currentRightImage, depth] = blenderClient.connectAndCapture(app.BlendClient, width, height);

               % If we gotta snick fricken use it.
               if ~isempty(app.SnickClient)
                   snickerdoodleClient.sendImages(app.SnickClient, ...
                       app.currentLeftImage, ...
                       app.baselineLeft, ...
                       app.currentRightImage,...
                       app.baselineRight)

                   [procImg1, procImg2] = snickerdoodleClient.requestProcessedFrames(app.SnickClient, ...
                       width, ...
                       height)
                   % Display the images in the GUI axes
                   imshow(procImg1, 'Parent', app.LeftCameraView);
                   imshow(procImg2, 'Parent', app.RightCameraView);
               else % fall back to imgs from blender if no snickers bar :D
               % Display the images in the GUI axes
                   imshow(app.currentLeftImage, 'Parent', app.LeftCameraView);
                   imshow(app.currentRightImage, 'Parent', app.RightCameraView);
               end

               disp(depth);
           end
        end
%--------------------------------------------------------------------------
%----- FILE FUNCTIONS -----------------------------------------------------
%--------------------------------------------------------------------------
        function addSnickDataToFile(app, x, z, y)
            % Open or create the file in append mode
            fid = fopen('snickdata.dat', 'a');
            
            % Check if the file opened successfully
            if fid == -1
                error('Failed to open or create snickdata.dat');
            end
        
            % Write the data as a new line
            fprintf(fid, '%.6f %.6f %.6f\n', x, z, y);
        
            % Close the file
            fclose(fid);
        end

        function wipeSnickDataFile(app)
            % Open the file in write mode, which clears the contents
            fid = fopen('snickdata.dat', 'w');
            
            % Check if the file opened successfully
            if fid == -1
                error('Failed to open snickdata.dat for wiping');
            end
        
            % Immediately close the file, leaving it empty
            fclose(fid);
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            thisFile = mfilename('fullpath');
            appRoot = fileparts(thisFile);

            % -------------------------
            % ------ COR Setup -----
            % -------------------------
            app.CORFolderPath = fullfile(appRoot, "CoefficientRestitution");
            if ~exist(app.CORFolderPath, "dir")
                uialert(app.UIFigure, "CoefficientRestitution folder not found.", "Path Error");
            else
                % Makes baselineCOR / measureCOR functions visible
                addpath(app.CORFolderPath);
            end

            % -------------------------
            % ----- Blender Setup -----
            % -------------------------
            app.BlenderFolderPath = fullfile(appRoot, "BlenderConnection");
            if ~exist(app.BlenderFolderPath, "dir")
                uialert(app.UIFigure, "Blender folder not found.", "Path Error");
            else
                % Makes BlenderConnection Class visable
                addpath(app.BlenderFolderPath);
            end

            % -------------------------
            % ----- Snick Setup -----
            % -------------------------
            app.SnickFolderPath = fullfile(appRoot, "SnickConnection");
            if ~exist(app.SnickFolderPath, "dir")
                uialert(app.UIFigure, "Snick folder not found.", "Path Error");
            else
                % Makes SnickConnection Class visable
                addpath(app.SnickFolderPath);
            end

            app.DataFileFolder = fullfile(appRoot, '..', 'TennisTracker', 'ServeVollyDataFiles');

            if ~exist(app.DataFileFolder, 'dir')
                uialert(app.UIFigure, 'Data folder not found.', 'Path Error');
                return;
            end
            % Load baseline images from local files
            leftBaselinePath  = fullfile(appRoot, ...
                "..", ...
                "TennisTracker", ...
                "SupportingRepos", ...
                "python-zynq-server", ...
                "test-images", ...
                "leftBaseline.jpg");
            rightBaselinePath = fullfile(appRoot, ...
                "..", ...
                "TennisTracker", ...
                "SupportingRepos", ...
                "python-zynq-server", ...
                "test-images", ...
                "rightBaseline.jpg");
            
            app.baselineLeft  = imread(leftBaselinePath);
            app.baselineRight = imread(rightBaselinePath);

            % -------------------------
            % ----- GUI Setup -----
            % -------------------------
            app.SelectDataFileButton.Enable = 'off';
            app.StartServeVolleyButton.Enable = 'off';

            % -------------------------------
            % ----- Snickerdoodle Setup -----
            % -------------------------------

            % Snickerdoodle Data Watchdog
            app.GlobalDataFilePath = fullfile(pwd, 'snickdata.dat');
            if isfile(app.GlobalDataFilePath)
                fileInfo = dir(app.GlobalDataFilePath);
                app.LastModifiedTime = fileInfo.datenum;
            else
                app.LastModifiedTime = datetime("now");
            end
        
            % Create Timer
            app.FileMonitorTimer = timer('ExecutionMode', 'fixedRate', ...
                                         'Period', 1.0, ...
                                         'TimerFcn', @(~,~) app.checkFileUpdate());
            % Start Timer
            start(app.FileMonitorTimer);
            pause(0.1); % very tiny pause to let App Designer stabilize
            app.loadGlobalXYZData(); % Now safely load the table

%--------------------------------------------------------------------------
            %Static Accuracy Camera Plot
            %img = imread("SysAccuracyCamPos/ErrorPlot.png");
            %axes(app.CamAccuracy);
            %imshow(img, 'Parent', app.CamAccuracy);
            %axis(app.CamAccuracy, 'image');
            %axis(app.CamAccuracy, 'off');
            %max_error_cm = 0.1455;   % Example value (centimeters)
            %overall_accuracy = 98.57;  % Example value (percentage)
   
            %app.ErrorLabel.Text = sprintf('Max Error:+/- %.4f', max_error_cm);
            %app.AccuracyLabel.Text = sprintf('Retained Accuracy:\n %.2f %%', overall_accuracy);

            app.SendCalibration.Enable = 'off';
            %app.SelectDataFileButton.Enable = 'off';
            %app.ReplayButton.Enable = 'off';
        end

        % Button pushed function: BlendConnectButton
        function BlendConnectButtonPushed(app, event)
            blenderConnection(app)
        end

        % Button pushed function: BlendDisconnetButton
        function BlendDisconnetButtonPushed(app, event)
            blenderDisconnect(app)
        end

        % Button pushed function: SnickConnectButton
        function SnickConnectButtonPushed(app, event)
            snickConnection(app)
        end

        % Button pushed function: SnickDisconnetButton
        function SnickDisconnetButtonPushed(app, event)
            snickDisconnect(app)
        end

        % Button pushed function: SelectDataFileButton
        function SelectDataFileButtonPushed(app, event)
            readDatFileForBlender(app)
        end

        % Button pushed function: StartServeVolleyButton
        function StartServeVolleyButtonPushed(app, event)
            blenderObjectXform(app)
        end

        % Button pushed function: AddImagesButton
        function AddImagesButtonPushed(app, event)
            % BLOCKING: Prompt before selecting left folder
            choice = questdlg('Please select the LEFT camera image folder.', ...
                              'Select Left Folder', ...
                              'Continue', 'Cancel', 'Continue');
        
            if ~strcmp(choice, 'Continue')
                return;
            end
        
            leftDir = uigetdir(pwd, 'Select Left Camera Image Folder');
            if leftDir == 0
                return;
            end
            app.LeftImageFolderPath = leftDir;
        
            % BLOCKING: Prompt before selecting right folder
            choice = questdlg('Now select the RIGHT camera image folder.', ...
                              'Select Right Folder', ...
                              'Continue', 'Cancel', 'Continue');
        
            if ~strcmp(choice, 'Continue')
                return;
            end
        
            rightDir = uigetdir(pwd, 'Select Right Camera Image Folder');
            if rightDir == 0
                return;
            end
            app.RightImageFolderPath = rightDir;
        
            % Final confirmation
            uialert(app.UIFigure, 'Image folders loaded. You may now click Refresh.', 'Success');
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            [app.stereoParams, I1, I2, estimationErrors] = calibrateStereoCameras(app);
            plotReprojectionErrors(app, app.stereoParams);
            plotExtrinsics(app, app.stereoParams);
            updateCameraTable(app, app.stereoParams);
            app.SendCalibration.Enable = 'on';
        end

        % Button pushed function: SendCalibration
        function SendCalibrationButtonPushed(app, event)
            updateSnickerdoodleParam(app);
        end

        % Button pushed function: BaselineCORButton
        function BaselineCORButtonPushed(app, event)
            runBaselineCOR(app.CORAxes,4,app);
        end

        % Button pushed function: MeasureCORButton
        function MeasureCORButtonPushed(app, event)
            surface = app.SurfaceDropDown.Value;
            try
                measureCOR(surface, app);
            catch ME
                uialert(app.UIFigure, ME.message, "COR Test Error");
            end
        end

        % Button pushed function: LoadMeasuredDataButton
        function LoadMeasuredDataButtonPushed(app, event)
            %looks up calculated coordinates and displays them 
            thisFile = mfilename('fullpath');
            appRoot = fileparts(thisFile);
            dataFolder = fullfile(appRoot, 'TennisTracker', 'data');

            if ~exist(dataFolder, 'dir')
                uialert(app.UIFigure, 'Data folder not found.', 'Path Error');
                return;
            end

            [fileName, filePath] = uigetfile('*.dat', 'Select Measured Data File', dataFolder);

            if isequal(fileName, 0)
                disp('User cancelled.');
                return;
            end

            app.MeasuredData = readmatrix(fullfile(filePath, fileName));
            if ~isempty(app.GroundTruthData) && ~isempty(app.MeasuredData)
                plotResults(app);
            end

        end

        % Button pushed function: LoadGroundTruthButton
        function LoadGroundTruthButtonPushed(app, event)
             %looks up expected ground truths and displays them 
            thisFile = mfilename('fullpath');
            appRoot = fileparts(thisFile);
            dataFolder = fullfile(appRoot, 'TennisTracker', 'data');

            if ~exist(dataFolder, 'dir')
                uialert(app.UIFigure, 'Data folder not found.', 'Path Error');
                return;
            end

            [fileName, filePath] = uigetfile('*.dat', 'Select Ground Truth File', dataFolder);

            if isequal(fileName, 0)
                disp('User cancelled.');
                return;
            end

            app.GroundTruthData = readmatrix(fullfile(filePath, fileName));
            if ~isempty(app.GroundTruthData) && ~isempty(app.MeasuredData)
                plotResults(app);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TennisTrackerTab = uitab(app.TabGroup);
            app.TennisTrackerTab.Title = 'Tennis Tracker';
            app.LeftCameraView = uiaxes(app.TennisTrackerTab);
            title(app.LeftCameraView, 'Left Camera View')
            app.LeftCameraView.XTick = [];
            app.LeftCameraView.YTick = [];
            app.RightCameraView = uiaxes(app.TennisTrackerTab);
            title(app.RightCameraView, 'Right Camera View')
            app.RightCameraView.XTick = [];
            app.RightCameraView.YTick = [];
            app.DataTable = uitable(app.TennisTrackerTab);
            app.DataTable.ColumnName = {'X'; 'Y'; 'Z'};
            app.DataTable.RowName = {};
            app.CalulatedCoordinateDataViewLabel = uilabel(app.TennisTrackerTab);
            app.CalulatedCoordinateDataViewLabel.Text = 'Calulated Coordinate Data View';
            app.BounceBoundsLampLabel = uilabel(app.TennisTrackerTab);
            app.BounceBoundsLampLabel.Text = 'Bounce/Bounds';
            app.BounceBoundsGuideText = uitextarea(app.TennisTrackerTab);
            app.BounceBoundsGuideText.Value = {'Bounce/ Bounds'; 'Green = On Ground, In Bounds'; 'Red= On Ground, Out of Bounds'; 'Gray= In the Air'};
            app.BounceBoundsLamp = uilamp(app.TennisTrackerTab);
            app.BlendDisconnetButton = uibutton(app.TennisTrackerTab, 'push');
            app.BlendDisconnetButton.ButtonPushedFcn = createCallbackFcn(app, @BlendDisconnetButtonPushed, true);
            app.BlendDisconnetButton.Text = 'Disconnet';
            app.BlendConnectButton = uibutton(app.TennisTrackerTab, 'push');
            app.BlendConnectButton.ButtonPushedFcn = createCallbackFcn(app, @BlendConnectButtonPushed, true);
            app.BlendConnectButton.Text = 'Connect';
            app.BlenderConnectionLabel = uilabel(app.TennisTrackerTab);
            app.BlenderConnectionLabel.Text = 'Blender Connection';
            app.BlendConnectionLED = uilamp(app.TennisTrackerTab);
            app.BlenderConnectionLabel_2 = uilabel(app.TennisTrackerTab);
            app.BlenderConnectionLabel_2.Text = 'Snick Connection';
            app.SnickDisconnetButton = uibutton(app.TennisTrackerTab, 'push');
            app.SnickDisconnetButton.ButtonPushedFcn = createCallbackFcn(app, @SnickDisconnetButtonPushed, true);
            app.SnickDisconnetButton.Text = 'Disconnet';
            app.SnickConnectButton = uibutton(app.TennisTrackerTab, 'push');
            app.SnickConnectButton.ButtonPushedFcn = createCallbackFcn(app, @SnickConnectButtonPushed, true);
            app.SnickConnectButton.Text = 'Connect';
            app.SnickConnectionLED = uilamp(app.TennisTrackerTab);
            app.SelectDataFileButton = uibutton(app.TennisTrackerTab, 'push');
            app.SelectDataFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFileButtonPushed, true);
            app.SelectDataFileButton.Text = 'Select Data File';
            app.StartServeVolleyButton = uibutton(app.TennisTrackerTab, 'push');
            app.StartServeVolleyButton.ButtonPushedFcn = createCallbackFcn(app, @StartServeVolleyButtonPushed, true);
            app.StartServeVolleyButton.Text = 'Start Serve/Volley';
            app.StereoCalibratorTab = uitab(app.TabGroup);
            app.StereoCalibratorTab.Title = 'Stereo Calibrator';
            app.UIAxes = uiaxes(app.StereoCalibratorTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.StereoCalibratorTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.AddImagesButton = uibutton(app.StereoCalibratorTab, 'push');
            app.AddImagesButton.ButtonPushedFcn = createCallbackFcn(app, @AddImagesButtonPushed, true);
            app.AddImagesButton.Text = 'Add Images';
            app.RefreshButton = uibutton(app.StereoCalibratorTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.BoardPlacementVisualizationLabel = uilabel(app.StereoCalibratorTab);
            app.BoardPlacementVisualizationLabel.Text = 'Board Placement Visualization';
            app.PropogationErrorLabel = uilabel(app.StereoCalibratorTab);
            app.PropogationErrorLabel.Text = 'Propogation Error';
            app.UITable = uitable(app.StereoCalibratorTab);
            app.UITable.ColumnName = {'Camera'; 'X'; 'Y'; 'Z'; 'f'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.GuideTextAreaLabel = uilabel(app.StereoCalibratorTab);
            app.GuideTextAreaLabel.Text = 'Guide';
            app.GuideTextArea = uitextarea(app.StereoCalibratorTab);
            app.GuideTextArea.Value = {'Please add file path for left and right images'};
            app.SendCalibration = uibutton(app.StereoCalibratorTab, 'push');
            app.SendCalibration.ButtonPushedFcn = createCallbackFcn(app, @SendCalibrationButtonPushed, true);
            app.SendCalibration.Text = 'Calibrate Board';
            app.CORTab = uitab(app.TabGroup);
            app.CORTab.Title = 'COR';
            app.CORAxes = uiaxes(app.CORTab);
            app.BaselineCORButton = uibutton(app.CORTab, 'push');
            app.BaselineCORButton.ButtonPushedFcn = createCallbackFcn(app, @BaselineCORButtonPushed, true);
            app.BaselineCORButton.Text = 'Baseline COR';
            app.MeasureCORButton = uibutton(app.CORTab, 'push');
            app.MeasureCORButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureCORButtonPushed, true);
            app.MeasureCORButton.Text = 'Measure COR';
            app.CORTable = uitable(app.CORTab);
            app.CORTable.ColumnName = {'Surface'; 'Bounce #'; 'Velocity'; 'E'};
            app.CORTable.RowName = {};
            app.SurfaceDropDownLabel = uilabel(app.CORTab);
            app.SurfaceDropDownLabel.Text = 'Surface';
            app.SurfaceDropDown = uidropdown(app.CORTab);
            app.SurfaceDropDown.Items = {'Clay', 'Grass', 'Hard'};
            app.SurfaceDropDown.Value = 'Clay';
            app.AccuracyPlotsTab = uitab(app.TabGroup);
            app.AccuracyPlotsTab.Title = 'Accuracy Plots';
            app.CamAccuracy = uiaxes(app.AccuracyPlotsTab);
            app.CamAccuracy.XTick = [];
            app.CamAccuracy.YTick = [];
            app.BallAccuracyX = uiaxes(app.AccuracyPlotsTab);
            title(app.BallAccuracyX, 'Title')
            xlabel(app.BallAccuracyX, 'X')
            ylabel(app.BallAccuracyX, 'Y')
            zlabel(app.BallAccuracyX, 'Z')
            app.BallAccuracyY = uiaxes(app.AccuracyPlotsTab);
            title(app.BallAccuracyY, 'Title')
            xlabel(app.BallAccuracyY, 'X')
            ylabel(app.BallAccuracyY, 'Y')
            zlabel(app.BallAccuracyY, 'Z')
            app.BallAccuracyZ = uiaxes(app.AccuracyPlotsTab);
            title(app.BallAccuracyZ, 'Title')
            xlabel(app.BallAccuracyZ, 'X')
            ylabel(app.BallAccuracyZ, 'Y')
            zlabel(app.BallAccuracyZ, 'Z')
            app.BasedonBallPositionLabel = uilabel(app.AccuracyPlotsTab);
            app.BasedonBallPositionLabel.Text = 'Based on Ball Position';
            app.BasedonCameraPositionLabel = uilabel(app.AccuracyPlotsTab);
            app.BasedonCameraPositionLabel.Text = 'Based on Camera Position ';
            app.SystemAccuracyLabel = uilabel(app.AccuracyPlotsTab);
            app.SystemAccuracyLabel.Text = 'System Accuracy';
            app.ErrorLabel = uilabel(app.AccuracyPlotsTab);
            app.ErrorLabel.Text = 'Error';
            app.AccuracyLabel = uilabel(app.AccuracyPlotsTab);
            app.AccuracyLabel.Text = 'Accuracy %';
            app.OverallAccuracyLabel = uilabel(app.AccuracyPlotsTab);
            app.OverallAccuracyLabel.Text = 'Overall Accuracy:';
            app.LoadGroundTruthButton = uibutton(app.AccuracyPlotsTab, 'push');
            app.LoadGroundTruthButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGroundTruthButtonPushed, true);
            app.LoadGroundTruthButton.Text = 'Load Ground Truth';
            app.LoadMeasuredDataButton = uibutton(app.AccuracyPlotsTab, 'push');
            app.LoadMeasuredDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMeasuredDataButtonPushed, true);
            app.LoadMeasuredDataButton.Text = 'Load Measured Data';
            app.PlaybackTab = uitab(app.TabGroup);
            app.PlaybackTab.Title = 'Playback';
            app.ReplayGraphL = uiaxes(app.PlaybackTab);
            title(app.ReplayGraphL, 'Left Camera View')
            app.ReplayGraphL.XTick = [];
            app.ReplayGraphL.YTick = [];
            app.TrajectoryGraph = uiaxes(app.PlaybackTab);
            title(app.TrajectoryGraph, 'Title')
            xlabel(app.TrajectoryGraph, 'X')
            ylabel(app.TrajectoryGraph, 'Y')
            zlabel(app.TrajectoryGraph, 'Z')
            app.ReplayGraphR = uiaxes(app.PlaybackTab);
            title(app.ReplayGraphR, 'Right Camera View')
            app.ReplayGraphR.XTick = [];
            app.ReplayGraphR.YTick = [];
            app.ReplayPOVLabel = uilabel(app.PlaybackTab);
            app.ReplayPOVLabel.Text = 'Replay POV';
            app.TrajectoryGraphLabel = uilabel(app.PlaybackTab);
            app.TrajectoryGraphLabel.Text = 'Trajectory Graph';
            app.XEditFieldLabel = uilabel(app.PlaybackTab);
            app.XEditFieldLabel.Text = 'X';
            app.PlayBackMenuLabel = uilabel(app.PlaybackTab);
            app.PlayBackMenuLabel.Text = 'PlayBack Menu';
            app.YLabel = uilabel(app.PlaybackTab);
            app.YLabel.Text = 'Y';
            app.XDisplay = uieditfield(app.PlaybackTab, 'numeric');
            app.XDisplay.Editable = 'off';
            app.YDisplay = uieditfield(app.PlaybackTab, 'numeric');
            app.YDisplay.Editable = 'off';
            app.ReplayButton = uibutton(app.PlaybackTab, 'push');
            app.ReplayButton.Text = 'Replay';
            app.ZLabel = uilabel(app.PlaybackTab);
            app.ZLabel.Text = 'Z';
            app.ZDisplay = uieditfield(app.PlaybackTab, 'numeric');
            app.ZDisplay.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TennisProjectGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end