classdef TennisProjectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        TennisTrackerTab            matlab.ui.container.Tab
        GuideTextArea_2             matlab.ui.control.TextArea
        GuideTextArea_2Label        matlab.ui.control.Label
        BounceBoundsLamp            matlab.ui.control.Lamp
        BounceBoundsLampLabel       matlab.ui.control.Label
        UITable2                    matlab.ui.control.Table
        StereoCalibratorTab         matlab.ui.container.Tab
        SendCalibration             matlab.ui.control.Button
        GuideTextArea               matlab.ui.control.TextArea
        GuideTextAreaLabel          matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        PropogationErrorLabel       matlab.ui.control.Label
        BoardPlacementVisualizationLabel  matlab.ui.control.Label
        RefreshButton               matlab.ui.control.Button
        AddImagesButton             matlab.ui.control.Button
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        CORTab                      matlab.ui.container.Tab
        SurfaceDropDown             matlab.ui.control.DropDown
        SurfaceDropDownLabel        matlab.ui.control.Label
        CORTable                    matlab.ui.control.Table
        MeasureCORButton            matlab.ui.control.Button
        BaselineCORButton           matlab.ui.control.Button
        CORAxes                     matlab.ui.control.UIAxes
        AccuracyPlotsTab            matlab.ui.container.Tab
        LoadMeasuredDataButton      matlab.ui.control.Button
        LoadGroundTruthButton       matlab.ui.control.Button
        OverallAccuracyLabel        matlab.ui.control.Label
        AccuracyLabel               matlab.ui.control.Label
        ErrorLabel                  matlab.ui.control.Label
        SystemAccuracyLabel         matlab.ui.control.Label
        BasedonCameraPositionLabel  matlab.ui.control.Label
        BasedonBallPositionLabel    matlab.ui.control.Label
        BallAccuracyZ               matlab.ui.control.UIAxes
        BallAccuracyY               matlab.ui.control.UIAxes
        BallAccuracyX               matlab.ui.control.UIAxes
        CamAccuracy                 matlab.ui.control.UIAxes
        PlaybackTab                 matlab.ui.container.Tab
        ZDisplay                    matlab.ui.control.NumericEditField
        ZLabel                      matlab.ui.control.Label
        YDisplay                    matlab.ui.control.NumericEditField
        YLabel                      matlab.ui.control.Label
        XEditFieldLabel             matlab.ui.control.Label
        XDisplay                    matlab.ui.control.NumericEditField
        BlendConnectionLED          matlab.ui.control.Lamp
        BlendConnectionLampLabel    matlab.ui.control.Label
        BlendConnectButton          matlab.ui.control.Button
        BlendDisconnetButton        matlab.ui.control.Button
        ServeVolleyButton           matlab.ui.control.Button
        ReplayButton                matlab.ui.control.Button
        TrajectoryGraphLabel        matlab.ui.control.Label
        ReplayPOVLabel              matlab.ui.control.Label
        PlayBackMenuLabel           matlab.ui.control.Label
        ReplayGraphR                matlab.ui.control.UIAxes
        TrajectoryGraph             matlab.ui.control.UIAxes
        ReplayGraphL                matlab.ui.control.UIAxes
        SettingsTab                 matlab.ui.container.Tab
        SnickerdoodleSettingsLabel  matlab.ui.control.Label
        ConnectionLED               matlab.ui.control.Lamp
        ConnectionStatusLampLabel   matlab.ui.control.Label
        CloseServer                 matlab.ui.control.Button
        ConnectServer               matlab.ui.control.Button
        DataTab                     matlab.ui.container.Tab
    end

    
    properties (Access = private)
        % Connections
        BlendClient 
        SnickClient

        % Stereo Calibrator Image Paths & Data
        LeftImageFolderPath  
        RightImageFolderPath
        stereoParams

        % COR Properties
        CORFolderPath string % Path to COR Scripts
        sampledServe % data plots
        GroundTruthData % Expected Values for accuracy plots
        MeasuredData % Calculated Values for plots

        %Raw Data
        LastModifiedTime % Last known modification timestamp of the .dat file
        FileMonitorTimer % Timer object for auto-updating
        GlobalDataFilePath % Full path to your XYZ-Time .dat file
    end


    methods (Access = private)
%----------------------------------------------------------------------------------------------------------------------------------------------------------------
        function plotExtrinsics(app, stereoParams)
            tempFig = figure('Visible', 'off');
            showExtrinsics(stereoParams, 'CameraCentric');
            tempAx = gca;
            
            cla(app.UIAxes_2);
            copyobj(allchild(tempAx), app.UIAxes_2);
            app.UIAxes_2.XLim = tempAx.XLim;
            app.UIAxes_2.YLim = tempAx.YLim;
            app.UIAxes_2.ZLim = tempAx.ZLim;
            app.UIAxes_2.XColor = [1 1 1];
            app.UIAxes_2.YColor = [1 1 1];
            app.UIAxes_2.ZColor = [1 1 1];
            app.UIAxes_2.Box = 'on';
            close(tempFig);
        end

%----------------------------------------------------------------------------------------------------------------------------------------------------------------
        function plotReprojectionErrors(app, stereoParams)
            tempFig = figure('Visible', 'off');
            showReprojectionErrors(stereoParams);
            tempAx = gca;

            cla(app.UIAxes);
            copyobj(allchild(tempAx), app.UIAxes);
            app.UIAxes.XLim = tempAx.XLim;
            app.UIAxes.YLim = tempAx.YLim;
            app.UIAxes.XColor = [1 1 1];
            app.UIAxes.YColor = [1 1 1];
            app.UIAxes.Box = 'on';
            close(tempFig);
        end

%----------------------------------------------------------------------------------------------------------------------------------------------------------------   
        function [stereoParams, I1, I2, estimationErrors] = calibrateStereoCameras(app)
            % List all .png images in each selected folder
            leftFiles = dir(fullfile(app.LeftImageFolderPath, '*.png'));
            rightFiles = dir(fullfile(app.RightImageFolderPath, '*.png'));
            
            % Sort by filename to maintain order
            leftFiles = sort({leftFiles.name});
            rightFiles = sort({rightFiles.name});
            
            % Check for matching file counts
            if numel(leftFiles) ~= numel(rightFiles)
                uialert(app.UIFigure, 'Left and Right image folders must have the same number of images.', 'Image Count Mismatch');
                error('Image count mismatch between left and right folders.');
            end
            
            % Construct full file paths
            imageFileNames1 = fullfile(app.LeftImageFolderPath, leftFiles);
            imageFileNames2 = fullfile(app.RightImageFolderPath, rightFiles);


            detector = vision.calibration.stereo.CheckerboardDetector();
             [imagePoints, ~] = detectPatternPoints(detector, imageFileNames1, imageFileNames2);

            squareSize = 900;  % millimeters
            worldPoints = generateWorldPoints(detector, 'SquareSize', squareSize);

             I1 = imread(imageFileNames1{1});
             I2 = imread(imageFileNames2{1});
            [mrows, ncols, ~] = size(I1);

            [stereoParams, ~, estimationErrors] = estimateCameraParameters(imagePoints, worldPoints, ...
                'EstimateSkew', false, ...
                'EstimateTangentialDistortion', false, ...
                'NumRadialDistortionCoefficients', 2, ...
                'WorldUnits', 'millimeters', ...
                'ImageSize', [mrows, ncols]);
        end
%----------------------------------------------------------------------------------------------------------------------------------------------------------------
        function updateCameraTable(app, stereoParams)
            % Camera positions in world coordinates
            center1 = [0, 0, 0];
            R2 = stereoParams.RotationOfCamera2;
            T2 = stereoParams.TranslationOfCamera2';
            center2 = -R2' * T2;
            
            f1 = mean(stereoParams.CameraParameters1.FocalLength);
            f2 = mean(stereoParams.CameraParameters2.FocalLength);
            
            app.UITable.Data = {
                'Left',  center1(1), center1(2), center1(3), f1;
                'Right', center2(1), center2(2), center2(3), f2;
            };
end
%----------------------------------------------------------------------------------------------------------------------------------------------------------------

        function plotResults(app)
            TruthData = app.GroundTruthData;
            Measured = app.MeasuredData;
            
            if size(TruthData,1) ~= size(Measured,1)
                uialert(app.UIFigure, 'Datasets must have the same number of points!', 'Size Error');
                return;
            end

            X_gt = TruthData(:,1);
            Y_gt = TruthData(:,2);
            Z_gt = TruthData(:,3);

            X_measured = Measured(:,1);
            Y_measured = Measured(:,2);
            Z_measured = Measured(:,3);
            
            % Plot for X Axis
            plot(app.BallAccuracyX, X_gt, X_measured, 'DisplayName', 'Measured');
            hold(app.BallAccuracyX, 'on');
            plot(app.BallAccuracyX, X_gt, X_gt, 'DisplayName', 'Ideal');
            hold(app.BallAccuracyX, 'off');
            grid(app.BallAccuracyX, 'on');
            xlabel(app.BallAccuracyX, 'Actual X');
            ylabel(app.BallAccuracyX, 'Measured X');
            title(app.BallAccuracyX, 'Horizontal (X-axis)');
            legend(app.BallAccuracyX, 'Location', 'best');

            % Plot for Y Axis
            plot(app.BallAccuracyY, Y_gt, Y_measured, 'DisplayName', 'Measured');
            hold(app.BallAccuracyY, 'on');
            plot(app.BallAccuracyY, Y_gt, Y_gt, 'DisplayName', 'Ideal');
            hold(app.BallAccuracyY, 'off');
            grid(app.BallAccuracyY, 'on');
            xlabel(app.BallAccuracyY, 'Actual Y');
            ylabel(app.BallAccuracyY, 'Measured Y');
            title(app.BallAccuracyY, 'Vertical (Y-axis)');
            legend(app.BallAccuracyY, 'Location', 'best');

            % Plot for Z Axis
            plot(app.BallAccuracyZ, Z_gt, Z_measured, 'DisplayName', 'Measured');
            hold(app.BallAccuracyZ, 'on');
            plot(app.BallAccuracyZ, Z_gt, Z_gt, 'DisplayName', 'Ideal');
            hold(app.BallAccuracyZ, 'off');
            grid(app.BallAccuracyZ, 'on');
            xlabel(app.BallAccuracyZ, 'Actual Z');
            ylabel(app.BallAccuracyZ, 'Measured Z');
            title(app.BallAccuracyZ, 'Depth (Z-axis)');
            legend(app.BallAccuracyZ, 'Location', 'best');

            abs_error_X = abs(X_measured - X_gt);
            abs_error_Y = abs(Y_measured - Y_gt);
            abs_error_Z = abs(Z_measured - Z_gt);

            %max_error_X = max(abs_error_X);
            %max_error_Y = max(abs_error_Y);
            %max_error_Z = max(abs_error_Z);

            accuracy_X = (1 - mean(abs_error_X) / mean(abs(X_gt))) * 100;
            accuracy_Y = (1 - mean(abs_error_Y) / mean(abs(Y_gt))) * 100;
            accuracy_Z = (1 - mean(abs_error_Z) / mean(abs(Z_gt))) * 100;
            % mean value as overall accuracy
            overall_accuracy = mean([accuracy_X, accuracy_Y, accuracy_Z]);


            % === Display on GUI ===
            app.OverallAccuracyLabel.Text = sprintf('System Accuracy: %.2f%%', overall_accuracy);

        end
%----------------------------------------------------------------------------------------------------------------------------------------------------------------
       function loadGlobalXYZData(app)
    if ~isfile(app.GlobalDataFilePath)
        return; % File missing, skip
    end

    rawData = readmatrix(app.GlobalDataFilePath);

    if size(rawData,2) ~= 4
        % Handle unexpected format gracefully
        return;
    end

    % Rearrange from X Z Y Time → X Y Z Time
    rearrangedData = rawData(:, [1, 3, 2, 4]);

    app.UITable2.Data = num2cell(rearrangedData);
end
%----------------------------------------------------------------------------------------------------------------------------------------------------------------
        function checkFileUpdate(app)
    if ~isfile(app.GlobalDataFilePath)
        return;
    end
%----------------------------------------------------------------------------------------------------------------------------------------------------------------
    fileInfo = dir(app.GlobalDataFilePath);
    currentModifiedTime = fileInfo.datenum;

    if currentModifiedTime > app.LastModifiedTime
        app.LastModifiedTime = currentModifiedTime;
        app.loadGlobalXYZData(); % <-- Update table
        app.updateBallStatusLamp(); % Update Lamp
    end
end
%----------------------------------------------------------------------------------------------------------------------------------------------------------------
        function updateBallStatusLamp(app)
            % Define court boundaries (meters)
            courtX_min = -5.5;
            courtX_max = 5.5;
            courtY_min = -11.88;
            courtY_max = 0;
            groundZThreshold = 0.2;  % touching ground if Z <= 20cm
        
            % Check if file exists
            if ~isfile(app.GlobalDataFilePath)
                app.BounceBoundsLamp.Color = [0.7 0.7 0.7]; % Gray if no data
                return;
            end
        
            % Read latest data
            rawData = readmatrix(app.GlobalDataFilePath);
        
            if isempty(rawData) || size(rawData,2) < 3
                app.BounceBoundsLamp.Color = [0.7 0.7 0.7]; % Gray if bad data
                return;
            end
        
            % Take the last row (most recent point)
            latestRow = rawData(end, :);
        
            x = latestRow(1);
            z = latestRow(2);
            y = latestRow(3);
        
            % Lamp logic
            if z <= groundZThreshold
                % Ball touching ground
                if (x >= courtX_min && x <= courtX_max) && (y >= courtY_min && y <= courtY_max)
                    % Inside court
                    app.BounceBoundsLamp.Color = 'green';
                else
                    % Touching ground but out of bounds
                    app.BounceBoundsLamp.Color = 'red';
                end
            else
                % In the air
                app.BounceBoundsLamp.Color = [0.7 0.7 0.7]; % Gray
            end
        end


%--------------------------------------------------------------------------
%----- SNICKERDOODLE FUNCTIONS --------------------------------------------
%--------------------------------------------------------------------------
        function connectToSnick(app)
            % Server Initialization Parameters
            server_ip = '10.200.191.13';
            server_port = 9999;
            
            try
                app.SnickClient = tcpclient(server_ip, server_port, "Timeout", 30);
                fprintf(1, "Connected to server\n");
                
                % Turn on green LED
                app.ConnectionLED.Color = 'green';
            catch ME
                fprintf(1, "Failed to connect to server: %s\n", ME.message);
                
                % Turn off LED if connection fails
                app.ConnectionLED.Color = 'red';
            end
        end
        
        function closeSnickConnection(app)
            if ~isempty(app.SnickClient)
                write(app.SnickClient, 'e');  % Signal server to close
                flush(app.SnickClient);       % Ensure the command is sent immediately
                fprintf(1, "Exit command sent to server. Closing connection.\n");
                
                clear app.SnickClient;  % Clear the client connection
            end
            
            % Turn off LED
            app.ConnectionLED.Color = 'red';
        end

        function sendImage(app, ballImage, baselineImage)
        % sendImage - Sends a ball image and baseline image over TCP to the server
        % Inputs:
        %   client            - TCP client object (must already be connected)
        %   ballImagePath     - Path to the ball image (e.g., 'rightBall.jpg')
        %   baselineImagePath - Path to the baseline image (e.g., 'rightBaseline.jpg'


            % Image Resolution
            width = 752;
            height = 480;
        
            % Read and resize the ball and baseline images
            dataBall = imread(ballImage);
            dataBaseline = imread(baselineImage);
        
            dataBall = imresize(dataBall, [height, width]);
            dataBaseline = imresize(dataBaseline, [height, width]);
        
            % Ensure images are 8-bit
            dataBall = uint8(dataBall);
            dataBaseline = uint8(dataBaseline);
        
            % Grayscale channel calculations
            dataGrayBall = im2gray(dataBall);
            dataGrayBaseline = im2gray(dataBaseline);
        
            % Prepare 64-bit pixel bus formatting
            imageStack = uint8(ones(height, width, 8));
            imageStack(:, :, 1:3) = dataBall;        % Channels 1–3: RGB of Ball image
            imageStack(:, :, 4) = dataGrayBall;      % Channel 4: Gray Ball
            imageStack(:, :, 5:7) = dataBaseline;    % Channels 5–7: RGB of Baseline
            imageStack(:, :, 8) = dataGrayBaseline;  % Channel 8: Gray Baseline
        
            % Reorder to [channel, column, row] for the hardware
            imageStack = permute(imageStack, [3 2 1]);
            
            % Send the image data
            write(app.SnickClient, imageStack(:));
            
            % Wait for acknowledgement (1 byte)
            ack = read(app.SnickClient, 1);
            fprintf(1, "Acknowledgement received: %d\n", ack);
        end

        function [leftImg, rightImg] = receiveImages(app)
        % receiveImages - Receives feedthrough and processed images from the server
        % Outputs:
        %   leftImg
        %   rightImg
        
            width = 752;
            height = 480;
            channels = 3;
        
            % Read and discard the left frame
            frameSize = width * height * channels; % 1,084,800 bytes
            rawLeft = [];
            while length(rawLeft) < frameSize
                rawLeft = [rawLeft; read(app.SnickClient, frameSize - length(rawLeft))];
            end
            temp = reshape(rawLeft, [3, width, height]);
            leftImg = permute(temp, [3 2 1]);
        
            % Read and discard the right frame
            rawRight = [];
            while length(rawRight) < frameSize
                rawRight = [rawRight; read(app.SnickClient, frameSize - length(rawRight))];
            end
            temp = reshape(rawRight, [3, width, height]);
            rightImg = permute(temp, [3 2 1]);
        
            fprintf(1, "Processed Image Received \n");
        end

        function updateSnickerdoodleParam(app)
            % Camera positions in world coordinates
            center1 = [0, 0, 0];
            R2 = app.stereoParams.RotationOfCamera2;
            T2 = app.stereoParams.TranslationOfCamera2';
            center2 = -R2' * T2;
        
            % Baseline & Focal Length
            baseLine = center2(1);
            focalLength = mean(app.stereoParams.CameraParameters1.FocalLength);
        
            write(app.SnickClient, '5');         % Signal start of transmission
            flush(app.SnickClient);              % Ensure the command is sent immediately
        
            % Create message
            message = sprintf('%.4f,%.4f', baseLine, focalLength);
            message_bytes = uint8(message);    % Convert to bytes
        
            % Prefix with length
            length_prefix = sprintf('%d:', numel(message_bytes));
            full_message = [uint8(length_prefix), message_bytes];
        
            % Send
            write(app.SnickClient, full_message);
            fprintf(1, "Values Sent (%d bytes)\n", length(full_message));
        
            % Wait for acknowledgment
            ack = read(app.SnickClient, 1);
            fprintf(1, "Acknowledgement received: %d\n", ack);
        end

        function configSnickerdoodleBlend(app)
            % Baseline & Focal Length
            baseLine = 3000;
            focalLength = 20.0;
        
            write(app.SnickClient, '5');         % Signal start of transmission
            flush(app.SnickClient);              % Ensure the command is sent immediately
        
            % Create message
            message = sprintf('%.4f,%.4f', baseLine, focalLength);
            message_bytes = uint8(message);    % Convert to bytes
        
            % Prefix with length
            length_prefix = sprintf('%d:', numel(message_bytes));
            full_message = [uint8(length_prefix), message_bytes];
        
            % Send
            write(app.SnickClient, full_message);
            fprintf(1, "Values Sent (%d bytes)\n", length(full_message));
        
            % Wait for acknowledgment
            ack = read(app.SnickClient, 1);
            fprintf(1, "Acknowledgement received: %d\n", ack);
        end

        function generateDataFile(app, x, z, y)
            % Open or create the file in append mode
            fid = fopen('snickdata.dat', 'a');
            
            % Check if the file opened successfully
            if fid == -1
                error('Failed to open or create snickdata.dat');
            end
        
            % Write the data as a new line
            fprintf(fid, '%.6f %.6f %.6f\n', x, z, y);
        
            % Close the file
            fclose(fid);
        end

        function wipeDataFile(app)
            % Open the file in write mode, which clears the contents
            fid = fopen('snickdata.dat', 'w');
            
            % Check if the file opened successfully
            if fid == -1
                error('Failed to open snickdata.dat for wiping');
            end
        
            % Immediately close the file, leaving it empty
            fclose(fid);
        end

        function [x, z, y] = getCoordinateData(app)
            write(app.SnickClient, '4');         % Signal start of transmission
            flush(app.SnickClient);              % Ensure the command is sent immediately
            
            % Now read one double at a time
            currentTimeRaw = read(app.SnickClient, 8);
            currentTime = typecast(uint8(currentTimeRaw), 'double');
            
            xRaw = read(app.SnickClient, 8);
            x = typecast(uint8(xRaw), 'double');
            
            yRaw = read(app.SnickClient, 8);
            y = typecast(uint8(yRaw), 'double');
            
            zRaw = read(app.SnickClient, 8);
            z = typecast(uint8(zRaw), 'double');
            
            generateDataFile(app, x, z, y);
            fprintf(1, "Current Time: %f, X: %f, Y: %f, Z: %f\n", currentTime, x, y, z);
        end
        

%--------------------------------------------------------------------------
%----- BLENDER FUNCTIONS --------------------------------------------
%--------------------------------------------------------------------------
function connectToBlend(app)
            % Server Initialization Parameters
            server_ip = '127.0.0.1';
            server_port = 55001;
            
            try
                app.BlendClient = tcpclient(server_ip,server_port,"Timeout",20);
                fprintf(1, "Connected to blender\n");
                
                % Turn on green LED
                app.BlendConnectionLED.Color = 'green';
                app.ServeVolleyButton.Enable = 'on';
                app.ReplayButton.Enable = 'on';
            catch ME
                fprintf(1, "Failed to connect to server: %s\n", ME.message);
                
                % Turn off LED if connection fails
                app.BlendConnectionLED.Color = 'red';
            end
        end
        
        function closeBlendConnection(app)
            if ~isempty(app.BlendClient)
                fprintf(1, "Exit command sent to server. Closing connection.\n");
                
                clear app.BlendClient;  % Clear the client connection
            end
            
            % Turn off LED
            app.BlendConnectionLED.Color = 'red';
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            thisFile = mfilename('fullpath');
            appRoot = fileparts(thisFile);
            app.CORFolderPath = fullfile(appRoot, "CoefficientRestitution");
            if ~exist(app.CORFolderPath, "dir")
                uialert(app.UIFigure, "CoefficientRestitution folder not found.", "Path Error");
            else
                addpath(app.CORFolderPath);      % makes baselineCOR / measureCOR visible
            end
%-------------------------Watchdog for snickerdoodle data ---------------------------------------------------
    app.GlobalDataFilePath = fullfile(pwd, 'snickdata.dat');
    if isfile(app.GlobalDataFilePath)
        fileInfo = dir(app.GlobalDataFilePath);
        app.LastModifiedTime = fileInfo.datenum;
    else
        app.LastModifiedTime = now;
    end

    % Create Timer
    app.FileMonitorTimer = timer('ExecutionMode', 'fixedRate', ...
                                 'Period', 1.0, ...
                                 'TimerFcn', @(~,~) app.checkFileUpdate());

    % Start Timer
    start(app.FileMonitorTimer);
   
    pause(0.1); % very tiny pause to let App Designer stabilize
    app.loadGlobalXYZData(); % Now safely load the table

%--------------------------------------------------------------------------------------------------------------
            %Static Accuracy Camera Plot
          %  img = imread("SysAccuracyCamPos/ErrorPlot.png");
          %  axes(app.CamAccuracy);
          %  imshow(img, 'Parent', app.CamAccuracy);
          %  axis(app.CamAccuracy, 'image');
          %  axis(app.CamAccuracy, 'off');
           % max_error_cm = 0.1455;   % Example value (centimeters)
           % overall_accuracy = 98.57;  % Example value (percentage)

   
            %app.ErrorLabel.Text = sprintf('Max Error:+/- %.4f', max_error_cm);
           % app.AccuracyLabel.Text = sprintf('Retained Accuracy:\n %.2f %%', overall_accuracy);

            %app.SendCalibration.Enable = 'off';
            %app.ServeVolleyButton.Enable = 'off';
            %app.ReplayButton.Enable = 'off';
        end

        % Button pushed function: AddImagesButton
        function AddImagesButtonPushed(app, event)
            % BLOCKING: Prompt before selecting left folder
            choice = questdlg('Please select the LEFT camera image folder.', ...
                              'Select Left Folder', ...
                              'Continue', 'Cancel', 'Continue');
        
            if ~strcmp(choice, 'Continue')
                return;
            end
        
            leftDir = uigetdir(pwd, 'Select Left Camera Image Folder');
            if leftDir == 0
                return;
            end
            app.LeftImageFolderPath = leftDir;
        
            % BLOCKING: Prompt before selecting right folder
            choice = questdlg('Now select the RIGHT camera image folder.', ...
                              'Select Right Folder', ...
                              'Continue', 'Cancel', 'Continue');
        
            if ~strcmp(choice, 'Continue')
                return;
            end
        
            rightDir = uigetdir(pwd, 'Select Right Camera Image Folder');
            if rightDir == 0
                return;
            end
            app.RightImageFolderPath = rightDir;
        
            % Final confirmation
            uialert(app.UIFigure, 'Image folders loaded. You may now click Refresh.', 'Success');
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            [stereoParams, I1, I2, estimationErrors] = calibrateStereoCameras(app);
            plotReprojectionErrors(app, stereoParams);
            plotExtrinsics(app, stereoParams);
            updateCameraTable(app, stereoParams);
            app.stereoParams = stereoParams
            app.SendCalibration.Enable = 'on';
        end

        % Button pushed function: SendCalibration
        function SendCalibrationButtonPushed(app, event)
            updateSnickerdoodleParam(app);
        end

        % Button pushed function: BaselineCORButton
        function BaselineCORButtonPushed(app, event)
            runBaselineCOR(app.CORAxes,4,app);
        end

        % Button pushed function: MeasureCORButton
        function MeasureCORButtonPushed(app, event)
            surface = app.SurfaceDropDown.Value;
            try
                e = measureCOR(surface, app);
            catch ME
                uialert(app.UIFigure, ME.message, "COR Test Error");
            end
        end

        % Callback function
        function SelectFileDropDownValueChanged(app, event)
       

        end

        % Button pushed function: ServeVolleyButton
        function ServeVolleyButtonPushed(app, event)
            thisFile = mfilename('fullpath');
            appRoot = fileparts(thisFile);
            dataFolder = fullfile(appRoot,'TennisTracker', 'data');
            
            if ~exist(dataFolder, 'dir')
            uialert(app.UIFigure, 'Data folder not found.', 'Path Error');
            return;
            end
            
            [fileName, filePath] = uigetfile('*.dat', 'Select a .dat file', dataFolder);
            
            if isequal(fileName, 0)
            disp('User cancelled file selection.');
            return;
            end
            
            fullFile = fullfile(filePath, fileName);
            
            % Read the selected file
            data = readmatrix(fullFile);
            
            % Data size
            dataSize = size(data);
            numFrames = dataSize(1);
            
            % Default timing (adjust depending on your needs)
            frameTimeMs = 50; % ms
            
            width = 752;
            height = 480;
            
            ballBase = 100;
            x =11;
            y =-1.5;
            z =8.2;
            
            % Move ball out of field of view of cameras and take baseline
            % images
            blenderLink(app.BlendClient, ...
                width, height, ...
                ballBase, ballBase, ballBase, ...
                0, 0, 0, ...
                "tennisBall");


            baselineLeft = blenderLink(app.BlendClient, ...
                width,height, ...
                x,y,z, ...
                45,0,90, ...
                "Left Camera");
            imagesc(app.ReplayGraphL, baselineLeft)
            imwrite(baselineLeft, "baselineL.jpg");
            
            y = 1.5;
            baselineRight = blenderLink(app.BlendClient, ...
                width,height, ...
                x,y,z, ...
                45,0,90, ...
                "Left Camera");
            imagesc(app.ReplayGraphR, baselineRight)
            imwrite(baselineRight, "baselineR.jpg");

            % Prepare data file and snickerdoodle
            configSnickerdoodleBlend(app);
            wipeDataFile(app)
            
            rows = numFrames / 50;
            
            i = 1;
            ii = 1;
            
            if numFrames == 1956
            frameTimeMs = 100;
            end
            if numFrames == 1062
            frameTimeMs = 75;
            end
            if numFrames == 3398
            frameTimeMs = 200;
            end
            if numFrames == 477
            frameTimeMs = 50;
            end
            if numFrames == 882
            frameTimeMs = 75;
            end
            
            outputFolder = fullfile(appRoot, 'ReplayImages');
            if ~exist(outputFolder, 'dir')
                mkdir(outputFolder);
            end

            for counter = 1:frameTimeMs:numFrames
                x = data(counter,1);
                y = data(counter,3);
                z = data(counter,2);
                x1 =11;
                y1 =-1.5;
                z1 =8.2;
                
                
                % Transform ball
                blenderLink(app.BlendClient,width,height,x,y,z,0,0,0,"tennisBall");

                % Left Image Capture
                leftImg = blenderLink(app.BlendClient,width,height,x1,y1,z1,45,0,90,"Left Camera");
                
                imwrite(leftImg, "leftImg.jpg");
                if i <=rows
                %exportgraphics(app.ReplayGraphL,servefilesleft(i,1))
                %savefig(servefilesleft(i,1))
                i = i +1;
                end
                
                % Right Image Capture
                y1 = 1.5;
                rightImg = blenderLink(app.BlendClient,width,height,x1,y1,z1,45,0,90,"Left Camera");
                
                imwrite(rightImg, "rightImg.jpg");
                if ii <=rows
                    %exportgraphics(app.ReplayGraphL,servefilesleft(ii,1))
                    %savefig(servefilesright(ii,1))
                    ii = ii +1;
                else
                    break;
                end
                    %set(gcf, 'Position', get(0, 'Screensize'));
                    % axis off

                % Send Data to Snickerdoodle
                write(app.SnickClient, '3');         % Signal start of transmission
                flush(app.SnickClient);              % Ensure the command is sent immediately
                sendImage(app, 'leftImg.jpg', 'baselineL.jpg')
                sendImage(app, 'rightImg.jpg', 'baselineR.jpg')

                [leftProcessed, rightProcessed] = receiveImages(app);
                imagesc(app.ReplayGraphL, leftProcessed)
                imagesc(app.ReplayGraphR, rightProcessed)
                [snickX, snickZ, snickY] = getCoordinateData(app);

                app.XDisplay.Value = snickX;
                app.YDisplay.Value = snickY;
                app.ZDisplay.Value = snickZ;
                
            end
            
            
            sampledServeCounter = 1;
            for counter = 1:frameTimeMs:numFrames
            x = data(counter,1);
            y = data(counter,3);
            z = data(counter,2);
            
            app.sampledServe(1, sampledServeCounter) = x;
            app.sampledServe(2, sampledServeCounter) = y;
            app.sampledServe(3, sampledServeCounter) = z;
            
            sampledServeCounter = sampledServeCounter + 1;
            end
            scatter3(app.TrajectoryGraph,app.sampledServe(1,:),app.sampledServe(2,:),app.sampledServe(3,:))
            app.BlendConnectionLED.Color = 'red';
            app.ServeVolleyButton.Enable = 'off';
            app.ReplayButton.Enable = 'off';

        end

        % Button pushed function: ReplayButton
        function ReplayButtonPushed(app, event)
            thisFile = mfilename('fullpath');
            appRoot = fileparts(thisFile);
            dataFolder = fullfile(appRoot,'TennisTracker', 'data');

            if ~exist(dataFolder, 'dir')
                uialert(app.UIFigure, 'Data folder not found.', 'Path Error');
                return;
            end

            [fileName, filePath] = uigetfile('*.dat', 'Select a .dat file', dataFolder);

            if isequal(fileName, 0)
                disp('User cancelled file selection.');
                return;
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%Change code ^^^^^^^^^ to take in the dat file instead of making user choice it
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            fullFile = fullfile(filePath, fileName);

            if isempty(app.BlendClient)
                server_ip   = '127.0.0.1';
                server_port = 55001;
                app.BlendClient = tcpclient(server_ip,server_port,"Timeout",20);
                disp('Connected to server');
            end

            width = 752;
            height = 480;


    % Read the selected file
            data = readmatrix('snickdata.dat');

    % Data size
            dataSize = size(data);
            numFrames = dataSize(1);

            replay = 1:numFrames; %1:frameTimeMs:numFrames;
            replayCount = length(replay);

            sampledServeCounter = 1;
        for counter = 1:1:numFrames
            x = data(counter,1);
            y = data(counter,3);
            z = data(counter,2);

            app.sampledServe(1, sampledServeCounter) = x;
            app.sampledServe(2, sampledServeCounter) = y;
            app.sampledServe(3, sampledServeCounter) = z;

            sampledServeCounter = sampledServeCounter + 1;
        end
            scatter3(app.TrajectoryGraph,app.sampledServe(1,:),app.sampledServe(2,:),app.sampledServe(3,:))


             % Define Camera Positions and Rotations
            CameraPositions = [
                7.5484 4.3444 1.5846;
                0.47844 12.38 1.1546;
                -1.1816 11 0.91456
            ];
            CameraRotations = [
                91 0 99;
                81 0 178;
                100 0 231
            ];
                % 3 Replays with different cameras
            for cam = 1:3
                x1 = CameraPositions(cam, 1);
                y1 = CameraPositions(cam, 2);
                z1 = CameraPositions(cam, 3);
                rx = CameraRotations(cam, 1);
                ry = CameraRotations(cam, 2);
                rz = CameraRotations(cam, 3);

                for i = 2:replayCount
                    idx = replay(i);

            % Ball position
                    x = data(idx, 1);
                    y = data(idx, 3);
                    z = data(idx, 2);

            % Send ball to Blender
                    temp = blenderLink(app.BlendClient, width, height, x, y, z, 0, 0, 0, "tennisBall");

            % Send camera view
                    image = blenderLink(app.BlendClient, width, height, x1, y1, z1, rx, ry, rz, "Left Camera");
                    imagesc(app.ReplayGraphL, image);
                    imagesc(app.ReplayGraphR, image);
                
                end
            app.BlendConnectionLED.Color = 'red';
            app.ServeVolleyButton.Enable = 'off';
            app.ReplayButton.Enable = 'off';
            end
        end

        % Button pushed function: LoadMeasuredDataButton
        function LoadMeasuredDataButtonPushed(app, event)
            %looks up calculated coordinates and displays them 
            thisFile = mfilename('fullpath');
            appRoot = fileparts(thisFile);
            dataFolder = fullfile(appRoot, 'TennisTracker', 'data');

            if ~exist(dataFolder, 'dir')
                uialert(app.UIFigure, 'Data folder not found.', 'Path Error');
                return;
            end

            [fileName, filePath] = uigetfile('*.dat', 'Select Measured Data File', dataFolder);

            if isequal(fileName, 0)
                disp('User cancelled.');
                return;
            end

            app.MeasuredData = readmatrix(fullfile(filePath, fileName));
            if ~isempty(app.GroundTruthData) && ~isempty(app.MeasuredData)
                plotResults(app);
            end

        end

        % Button pushed function: LoadGroundTruthButton
        function LoadGroundTruthButtonPushed(app, event)
             %looks up expected ground truths and displays them 
            thisFile = mfilename('fullpath');
            appRoot = fileparts(thisFile);
            dataFolder = fullfile(appRoot, 'TennisTracker', 'data');

            if ~exist(dataFolder, 'dir')
                uialert(app.UIFigure, 'Data folder not found.', 'Path Error');
                return;
            end

            [fileName, filePath] = uigetfile('*.dat', 'Select Ground Truth File', dataFolder);

            if isequal(fileName, 0)
                disp('User cancelled.');
                return;
            end

            app.GroundTruthData = readmatrix(fullfile(filePath, fileName));
            if ~isempty(app.GroundTruthData) && ~isempty(app.MeasuredData)
                plotResults(app);
            end

        end

        % Button pushed function: ConnectServer
        function ConnectServerButtonPushed(app, event)
            connectToSnick(app);
        end

        % Button pushed function: CloseServer
        function CloseServerButtonPushed(app, event)
            closeSnickConnection(app);
        end

        % Button pushed function: BlendConnectButton
        function BlendConnectButtonPushed(app, event)
            connectToBlend(app)
        end

        % Button pushed function: BlendDisconnetButton
        function BlendDisconnetButtonPushed(app, event)
            closeBlendConnection(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TennisTrackerTab = uitab(app.TabGroup);
            app.TennisTrackerTab.Title = 'Tennis Tracker';
            app.UITable2 = uitable(app.TennisTrackerTab);
            app.UITable2.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITable2.RowName = {};
            app.BounceBoundsLampLabel = uilabel(app.TennisTrackerTab);
            app.BounceBoundsLampLabel.Text = 'Bounce/Bounds';
            app.BounceBoundsLamp = uilamp(app.TennisTrackerTab);
            app.GuideTextArea_2Label = uilabel(app.TennisTrackerTab);
            app.GuideTextArea_2Label.Text = 'Guide';
            app.GuideTextArea_2 = uitextarea(app.TennisTrackerTab);
            app.GuideTextArea_2.Value = {'Bounce/ Bounds'; ''; 'Green = On Ground, In Bounds'; ''; 'Red= On Ground, Out of Bounds'; ''; 'Gray= In the Air'};
            app.StereoCalibratorTab = uitab(app.TabGroup);
            app.StereoCalibratorTab.Title = 'Stereo Calibrator';
            app.UIAxes = uiaxes(app.StereoCalibratorTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.StereoCalibratorTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.AddImagesButton = uibutton(app.StereoCalibratorTab, 'push');
            app.AddImagesButton.ButtonPushedFcn = createCallbackFcn(app, @AddImagesButtonPushed, true);
            app.AddImagesButton.Text = 'Add Images';
            app.RefreshButton = uibutton(app.StereoCalibratorTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.BoardPlacementVisualizationLabel = uilabel(app.StereoCalibratorTab);
            app.BoardPlacementVisualizationLabel.Text = 'Board Placement Visualization';
            app.PropogationErrorLabel = uilabel(app.StereoCalibratorTab);
            app.PropogationErrorLabel.Text = 'Propogation Error';
            app.UITable = uitable(app.StereoCalibratorTab);
            app.UITable.ColumnName = {'Camera'; 'X'; 'Y'; 'Z'; 'f'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.GuideTextAreaLabel = uilabel(app.StereoCalibratorTab);
            app.GuideTextAreaLabel.Text = 'Guide';
            app.GuideTextArea = uitextarea(app.StereoCalibratorTab);
            app.GuideTextArea.Value = {'Please add file path for left and right images'};
            app.SendCalibration = uibutton(app.StereoCalibratorTab, 'push');
            app.SendCalibration.ButtonPushedFcn = createCallbackFcn(app, @SendCalibrationButtonPushed, true);
            app.SendCalibration.Text = 'Calibrate Board';
            app.CORTab = uitab(app.TabGroup);
            app.CORTab.Title = 'COR';
            app.CORAxes = uiaxes(app.CORTab);
            app.BaselineCORButton = uibutton(app.CORTab, 'push');
            app.BaselineCORButton.ButtonPushedFcn = createCallbackFcn(app, @BaselineCORButtonPushed, true);
            app.BaselineCORButton.Text = 'Baseline COR';
            app.MeasureCORButton = uibutton(app.CORTab, 'push');
            app.MeasureCORButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureCORButtonPushed, true);
            app.MeasureCORButton.Text = 'Measure COR';
            app.CORTable = uitable(app.CORTab);
            app.CORTable.ColumnName = {'Surface'; 'Bounce #'; 'Velocity'; 'E'};
            app.CORTable.RowName = {};
            app.SurfaceDropDownLabel = uilabel(app.CORTab);
            app.SurfaceDropDownLabel.Text = 'Surface';
            app.SurfaceDropDown = uidropdown(app.CORTab);
            app.SurfaceDropDown.Items = {'Clay', 'Grass', 'Hard'};
            app.SurfaceDropDown.Value = 'Clay';
            app.AccuracyPlotsTab = uitab(app.TabGroup);
            app.AccuracyPlotsTab.Title = 'Accuracy Plots';
            app.CamAccuracy = uiaxes(app.AccuracyPlotsTab);
            app.CamAccuracy.XTick = [];
            app.CamAccuracy.YTick = [];
            app.BallAccuracyX = uiaxes(app.AccuracyPlotsTab);
            title(app.BallAccuracyX, 'Title')
            xlabel(app.BallAccuracyX, 'X')
            ylabel(app.BallAccuracyX, 'Y')
            zlabel(app.BallAccuracyX, 'Z')
            app.BallAccuracyY = uiaxes(app.AccuracyPlotsTab);
            title(app.BallAccuracyY, 'Title')
            xlabel(app.BallAccuracyY, 'X')
            ylabel(app.BallAccuracyY, 'Y')
            zlabel(app.BallAccuracyY, 'Z')
            app.BallAccuracyZ = uiaxes(app.AccuracyPlotsTab);
            title(app.BallAccuracyZ, 'Title')
            xlabel(app.BallAccuracyZ, 'X')
            ylabel(app.BallAccuracyZ, 'Y')
            zlabel(app.BallAccuracyZ, 'Z')
            app.BasedonBallPositionLabel = uilabel(app.AccuracyPlotsTab);
            app.BasedonBallPositionLabel.Text = 'Based on Ball Position';
            app.BasedonCameraPositionLabel = uilabel(app.AccuracyPlotsTab);
            app.BasedonCameraPositionLabel.Text = 'Based on Camera Position ';
            app.SystemAccuracyLabel = uilabel(app.AccuracyPlotsTab);
            app.SystemAccuracyLabel.Text = 'System Accuracy';
            app.ErrorLabel = uilabel(app.AccuracyPlotsTab);
            app.ErrorLabel.Text = 'Error';
            app.AccuracyLabel = uilabel(app.AccuracyPlotsTab);
            app.AccuracyLabel.Text = 'Accuracy %';
            app.OverallAccuracyLabel = uilabel(app.AccuracyPlotsTab);
            app.OverallAccuracyLabel.Text = 'Overall Accuracy:';
            app.LoadGroundTruthButton = uibutton(app.AccuracyPlotsTab, 'push');
            app.LoadGroundTruthButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGroundTruthButtonPushed, true);
            app.LoadGroundTruthButton.Text = 'Load Ground Truth';
            app.LoadMeasuredDataButton = uibutton(app.AccuracyPlotsTab, 'push');
            app.LoadMeasuredDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMeasuredDataButtonPushed, true);
            app.LoadMeasuredDataButton.Text = 'Load Measured Data';
            app.PlaybackTab = uitab(app.TabGroup);
            app.PlaybackTab.Title = 'Playback';
            app.ReplayGraphL = uiaxes(app.PlaybackTab);
            app.ReplayGraphL.XTick = [];
            app.ReplayGraphL.YTick = [];
            app.TrajectoryGraph = uiaxes(app.PlaybackTab);
            title(app.TrajectoryGraph, 'Title')
            xlabel(app.TrajectoryGraph, 'X')
            ylabel(app.TrajectoryGraph, 'Y')
            zlabel(app.TrajectoryGraph, 'Z')
            app.ReplayGraphR = uiaxes(app.PlaybackTab);
            app.ReplayGraphR.XTick = [];
            app.ReplayGraphR.YTick = [];
            app.PlayBackMenuLabel = uilabel(app.PlaybackTab);
            app.PlayBackMenuLabel.Text = 'PlayBack Menu';
            app.ReplayPOVLabel = uilabel(app.PlaybackTab);
            app.ReplayPOVLabel.Text = 'Replay POV';
            app.TrajectoryGraphLabel = uilabel(app.PlaybackTab);
            app.TrajectoryGraphLabel.Text = 'Trajectory Graph';
            app.ReplayButton = uibutton(app.PlaybackTab, 'push');
            app.ReplayButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayButtonPushed, true);
            app.ReplayButton.Text = 'Replay';
            app.ServeVolleyButton = uibutton(app.PlaybackTab, 'push');
            app.ServeVolleyButton.ButtonPushedFcn = createCallbackFcn(app, @ServeVolleyButtonPushed, true);
            app.ServeVolleyButton.Text = 'Serve/Volley';
            app.BlendDisconnetButton = uibutton(app.PlaybackTab, 'push');
            app.BlendDisconnetButton.ButtonPushedFcn = createCallbackFcn(app, @BlendDisconnetButtonPushed, true);
            app.BlendDisconnetButton.Text = 'Disconnet';
            app.BlendConnectButton = uibutton(app.PlaybackTab, 'push');
            app.BlendConnectButton.ButtonPushedFcn = createCallbackFcn(app, @BlendConnectButtonPushed, true);
            app.BlendConnectButton.Text = 'Connect';
            app.BlendConnectionLampLabel = uilabel(app.PlaybackTab);
            app.BlendConnectionLampLabel.Text = 'Blend Connection';
            app.BlendConnectionLED = uilamp(app.PlaybackTab);
            app.XDisplay = uieditfield(app.PlaybackTab, 'numeric');
            app.XDisplay.Editable = 'off';
            app.XEditFieldLabel = uilabel(app.PlaybackTab);
            app.XEditFieldLabel.Text = 'X';
            app.YLabel = uilabel(app.PlaybackTab);
            app.YLabel.Text = 'Y';
            app.YDisplay = uieditfield(app.PlaybackTab, 'numeric');
            app.YDisplay.Editable = 'off';
            app.ZLabel = uilabel(app.PlaybackTab);
            app.ZLabel.Text = 'Z';
            app.ZDisplay = uieditfield(app.PlaybackTab, 'numeric');
            app.ZDisplay.Editable = 'off';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ConnectServer = uibutton(app.SettingsTab, 'push');
            app.ConnectServer.ButtonPushedFcn = createCallbackFcn(app, @ConnectServerButtonPushed, true);
            app.ConnectServer.Text = 'Connect to Snickerdoodle';
            app.CloseServer = uibutton(app.SettingsTab, 'push');
            app.CloseServer.ButtonPushedFcn = createCallbackFcn(app, @CloseServerButtonPushed, true);
            app.CloseServer.Text = 'Close Server';
            app.ConnectionStatusLampLabel = uilabel(app.SettingsTab);
            app.ConnectionStatusLampLabel.Text = 'Connection Status';
            app.ConnectionLED = uilamp(app.SettingsTab);
            app.SnickerdoodleSettingsLabel = uilabel(app.SettingsTab);
            app.SnickerdoodleSettingsLabel.Text = 'Snickerdoodle Settings';
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TennisProjectGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end