classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        ThresholdSlider       matlab.ui.control.Slider
        ThresholdSliderLabel  matlab.ui.control.Label
        RightPanel            matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        flag % Description
        x
        width
        height
        server_ip  
        server_port 
        client 
        threshold
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.width = 752;
            app.height = 480;
            app.server_ip   = '192.168.2.15';     % IP address of the server
            app.server_port = 9999;               % Server Port of the sever
            app.client = tcpclient(app.server_ip,app.server_port,"Timeout",30);
            app.x = 0;
            app.threshold = 100;

            while app.x < 100
                data = imread('sailboat.jpg');
                data = uint8(data);
                
                % mark the image number on the image
                data = insertText(data,[100 100],app.x,FontSize=42);
                
                dataGray = im2gray(data);
                imageStack = uint8(ones(app.height,app.width,8));
                imageStack(:,:,1:3) = data;
                imageStack(:,:,5:7) = data;
                imageStack(:,:,4) = dataGray;
                imageStack(:,:,8) = dataGray;
                imageStack = permute(imageStack,[3 2 1]);

                write(app.client,'0');
                flush(app.client);
                write(app.client,uint8(app.threshold));
                flush(app.client);
                write(app.client,imageStack(:));

                % keep these lines uncommented for
                % matlabServerStereoAxiLite.py
                % comment them out for matlabServerStreoAXILiteDT.py
                %temp = read(app.client,1)
                %write(app.client,'2');
                %flush(app.client);
                
                dataLeft = read(app.client,app.width*app.height);   
                temp = reshape(dataLeft,[app.width,app.height]);
                leftProcessed = permute(temp,[2 1]);
                dataRight = read(app.client,app.width*app.height);
                temp = reshape(dataRight,[app.width,app.height]);
                rightProcessed = permute(temp,[2 1]);

                imagesc(app.UIAxes,leftProcessed)
                colormap(app.UIAxes,gray)
                
                app.x = app.x + 1;
                disp("hello" + num2str(app.x))
            end
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            app.threshold = app.ThresholdSlider.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {363, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ThresholdSliderLabel = uilabel(app.LeftPanel);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.LeftPanel);
            app.ThresholdSlider.Limits = [0 255];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Thresholded Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end